classdef TrackDataApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        DataMenu              matlab.ui.container.Menu
        SelectStartPointMenu  matlab.ui.container.Menu
        TabGroup              matlab.ui.container.TabGroup
        GPSTab                matlab.ui.container.Tab
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes3               matlab.ui.control.UIAxes
        ACCELTab              matlab.ui.container.Tab
        UIAxes6               matlab.ui.control.UIAxes
        UIAxes7               matlab.ui.control.UIAxes
        UIAxes8               matlab.ui.control.UIAxes
        ROLLTab               matlab.ui.container.Tab
        UIAxes9               matlab.ui.control.UIAxes
        UIAxes10              matlab.ui.control.UIAxes
        UIAxes11              matlab.ui.control.UIAxes
        FrontPOTSTab          matlab.ui.container.Tab
        UIAxes12              matlab.ui.control.UIAxes
        UIAxes13              matlab.ui.control.UIAxes
        RearPOTSTab           matlab.ui.container.Tab
        UIAxes14              matlab.ui.control.UIAxes
        UIAxes15              matlab.ui.control.UIAxes
        BRAKESTab             matlab.ui.container.Tab
        UIAxes4               matlab.ui.control.UIAxes
        UIAxes5               matlab.ui.control.UIAxes
        General2              matlab.ui.container.Tab
        UIAxes16              matlab.ui.control.UIAxes
        UIAxes17              matlab.ui.control.UIAxes
        EngineTab             matlab.ui.container.Tab
        RPMGaugeLabel         matlab.ui.control.Label
        RPMGauge              matlab.ui.control.Gauge
        FDamoerSpeedTab       matlab.ui.container.Tab
        UIAxes18              matlab.ui.control.UIAxes
        UIAxes19              matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
        LapDropDownLabel      matlab.ui.control.Label
        LapDropDown           matlab.ui.control.DropDown
        ImportDataButton      matlab.ui.control.Button
        SelectDataButton      matlab.ui.control.Button
    end

    
    properties (Access = public)
        %Variables to be imported for plotted need to go here... Filtered
        %variables are used to accomodate for the different sampling
        %frequencies.
        
        %GENERAL
        Lat
        Long
        Speed
        Time
        RPM
        Steering_Angle
        Altitude
        
        %POTENTIOMETERS
        FL_Damper
        FR_Damper
        RL_Damper
        RR_Damper
        
        %BRAKES
        R_Brake
        F_Brake
        
        %ROLL
        Yaw
        Pitch
        Roll
        
        %ACCELERATION
        Accel_X
        Accel_Y
        Accel_Z
        
        %Filtered
        
        %GENERAL
        Lat_filtered
        Long_filtered
        Selected_Lat
        Selected_Long
        Speed_filtered
        Time_filtered
        RPM_filtered
        Steering_Angle_filtered
        Altitude_filtered
        
        %POTENTIOMETERS
        FL_Damper_filtered
        FR_Damper_filtered
        RL_Damper_filtered
        RR_Damper_filtered
        
        %BRAKES
        R_Brake_filtered
        F_Brake_filtered
        
        %ROLL
        Yaw_filtered
        Pitch_filtered
        Roll_filtered
        
        %ACCELERATION
        Accel_X_filtered
        Accel_Y_filtered
        Accel_Z_filtered
    end
    
    methods (Access = public)
        
        
        function results = Startup(app)
            
            %   This function imports the data and then plots about 11k points
            %   on the graph so the data can be seen. Next in the process is
            %   to select a "start point" for the track in order to separate
            %   the data lap by lap.
            
            data = uiimport('-file');   %   Need to be imported as vectors
            
            %Variables are assigned values based on the convention in the
            %test_log_1 excel headers
            
            %   Importing the data vectors... Removing the NAN's from the
            %   vectors... If there are issues in vector lengths, will need to
            %   cut out initial and final data where sensors aren't running
            
            LapNumber = length(data.textdata)./length(rmmissing(data.textdata));
            LapNumber = ceil(LapNumber);
            app.LapDropDown.Items = cellstr(num2str([1:1:LapNumber]'));
            app.LapDropDown.ItemsData = cellstr(num2str([1:1:LapNumber]'));
            
            %General
            app.Lat_filtered       = (data.data(:,1:LapNumber));
            app.Long_filtered      = (data.data(:,LapNumber + 1:2*LapNumber));
            app.Speed_filtered     = (data.data(:,LapNumber*2 + 1:3*LapNumber));
            app.Time_filtered      = (data.data(:,LapNumber*3 + 1:4*LapNumber));
            app.RPM_filtered       = (data.data(:,LapNumber*4 + 1:5*LapNumber));
            app.Steering_Angle_filtered = (data.data(:,LapNumber*5 + 1:6*LapNumber));
            app.Altitude_filtered  = (data.data(:,LapNumber*6 + 1:7*LapNumber));
            
            %POTENTIOMETERS
            app.FL_Damper_filtered = (data.data(:,LapNumber*7 + 1:8*LapNumber));
            app.FR_Damper_filtered = (data.data(:,LapNumber*8 + 1:9*LapNumber));
            app.RL_Damper_filtered = (data.data(:,LapNumber*9 + 1:10*LapNumber));
            app.RR_Damper_filtered = (data.data(:,LapNumber*10 + 1:11*LapNumber));
            
            %BRAKES
            app.R_Brake_filtered   = (data.data(:,LapNumber*11 + 1:12*LapNumber));
            app.F_Brake_filtered   = (data.data(:,LapNumber*12 + 1:13*LapNumber));
            
            %ROLL
            app.Yaw_filtered       = (data.data(:,LapNumber*13 + 1:14*LapNumber));
            app.Pitch_filtered     = (data.data(:,LapNumber*14 + 1:15*LapNumber));
            app.Roll_filtered      = (data.data(:,LapNumber*15 + 1:16*LapNumber));
            
            %ACCELERATION
            app.Accel_X_filtered   = (data.data(:,LapNumber*16 + 1:17*LapNumber));
            app.Accel_Y_filtered   = (data.data(:,LapNumber*17 + 1:18*LapNumber));
            app.Accel_Z_filtered   = (data.data(:,LapNumber*18 + 1:19*LapNumber));
            
            %   Filling with zeros for testing. Will either need to cut out data
            %   or find a way to make the vector lengths the same.
            %   app.Speed(numel(app.Long)) = 0;
            
            %   Manual Setpoints for the initial start of the "Laps" will
            %   implement another method for the user to be able to manually
            %   select and adjust these in the future.
            
            %   SelectStartPointMenuSelected(app, 0);
            
            app.Selected_Lat(1)  =  40.84479;
            app.Selected_Lat(2)  =  40.84487;
            app.Selected_Long(1) = -96.76793;
            app.Selected_Long(2) = -96.76790;
            
            colormap(app.UIAxes,hsv);
            scatter(app.UIAxes,app.Lat_filtered(:,2),app.Long_filtered(:,2),20,app.Speed_filtered(:,2));
            colorbar(app.UIAxes);
            
            PlotTestGraphsTab(app,str2num(app.LapDropDown.Value));
        end
    end

    
    methods (Access = public)
        
        function results = PlotTestGraphsTab(app,lap)
            %   GPS Tab
            scatter(app.UIAxes2,[1:1:length((app.Speed_filtered(:,lap)))],(app.Speed_filtered(:,lap)));
            scatter(app.UIAxes3,[1:1:length((app.Altitude_filtered(:,lap)))],(app.Altitude_filtered(:,lap)));
            %   Brakes Tab
            scatter(app.UIAxes4,[1:1:length((app.R_Brake_filtered(:,lap)))],(app.R_Brake_filtered(:,lap)));
            scatter(app.UIAxes5,[1:1:length((app.F_Brake_filtered(:,lap)))],(app.F_Brake_filtered(:,lap)));
            %   Accel Tab
            scatter(app.UIAxes6,[1:1:length((app.Accel_X_filtered(:,lap)))],(app.Accel_X_filtered(:,lap)));
            scatter(app.UIAxes7,[1:1:length((app.Accel_Y_filtered(:,lap)))],(app.Accel_Y_filtered(:,lap)));
            scatter(app.UIAxes8,[1:1:length((app.Accel_Z_filtered(:,lap)))],(app.Accel_Z_filtered(:,lap)));
            %   Front POTS
            scatter(app.UIAxes12,[1:1:length((app.FL_Damper_filtered(:,lap)))],(app.FL_Damper_filtered(:,lap)));
            scatter(app.UIAxes13,[1:1:length((app.FR_Damper_filtered(:,lap)))],(app.FR_Damper_filtered(:,lap)));
            
            %   Rear POTS
            scatter(app.UIAxes14,[1:1:length((app.RL_Damper_filtered(:,lap)))],(app.RL_Damper_filtered(:,lap)));
            scatter(app.UIAxes15,[1:1:length((app.RR_Damper_filtered(:,lap)))],(app.RR_Damper_filtered(:,lap)));
            
            %   ROLL
            scatter(app.UIAxes9, [1:1:length((app.Roll_filtered(:,lap)))],(app.Roll_filtered(:,lap)));
            scatter(app.UIAxes10,[1:1:length((app.Yaw_filtered(:,lap)))],(app.Yaw_filtered(:,lap)));
            scatter(app.UIAxes11,[1:1:length((app.Pitch_filtered(:,lap)))],(app.Pitch_filtered(:,lap)));
            
            %   General2 - Steering angle / RPM
            scatter(app.UIAxes16,[1:1:length((app.Steering_Angle_filtered(:,lap)))],(app.Steering_Angle_filtered(:,lap)));
            scatter(app.UIAxes17,[1:1:length((app.RPM_filtered(:,lap)))],(app.RPM_filtered(:,lap)));

            
        end
        
    end
    
    methods (Access = public)
        
        function results = PlotSelectedData(app,lap,index)
            %   GPS Tab
            scatter(app.UIAxes2,[1:1:length((app.Speed_filtered(index,lap)))],(app.Speed_filtered(index,lap)));
            scatter(app.UIAxes3,[1:1:length((app.Altitude_filtered(index,lap)))],(app.Altitude_filtered(index,lap)));
            %   Brakes Tab
            scatter(app.UIAxes4,[1:1:length((app.R_Brake_filtered(index,lap)))],(app.R_Brake_filtered(index,lap)));
            scatter(app.UIAxes5,[1:1:length((app.F_Brake_filtered(index,lap)))],(app.F_Brake_filtered(index,lap)));
            %   Accel Tab
            scatter(app.UIAxes6,[1:1:length((app.Accel_X_filtered(index,lap)))],(app.Accel_X_filtered(index,lap)));
            scatter(app.UIAxes7,[1:1:length((app.Accel_Y_filtered(index,lap)))],(app.Accel_Y_filtered(index,lap)));
            scatter(app.UIAxes8,[1:1:length((app.Accel_Z_filtered(index,lap)))],(app.Accel_Z_filtered(index,lap)));
            %   Front POTS
            scatter(app.UIAxes12,[1:1:length((app.FL_Damper_filtered(index,lap)))],(app.FL_Damper_filtered(index,lap)));
            scatter(app.UIAxes13,[1:1:length((app.FR_Damper_filtered(index,lap)))],(app.FR_Damper_filtered(index,lap)));
            
            %   Rear POTS
            scatter(app.UIAxes14,[1:1:length((app.RL_Damper_filtered(index,lap)))],(app.RL_Damper_filtered(index,lap)));
            scatter(app.UIAxes15,[1:1:length((app.RR_Damper_filtered(index,lap)))],(app.RR_Damper_filtered(index,lap)));
            
            %   ROLL
            scatter(app.UIAxes9, [1:1:length((app.Roll_filtered(index,lap)))],(app.Roll_filtered(index,lap)));
            scatter(app.UIAxes10,[1:1:length((app.Yaw_filtered(index,lap)))],(app.Yaw_filtered(index,lap)));
            scatter(app.UIAxes11,[1:1:length((app.Pitch_filtered(index,lap)))],(app.Pitch_filtered(index,lap)));
            
            %   General2 - Steering angle / RPM
            scatter(app.UIAxes16,[1:1:length((app.Steering_Angle_filtered(index,lap)))],(app.Steering_Angle_filtered(index,lap)));
            scatter(app.UIAxes17,[1:1:length((app.RPM_filtered(index,lap)))],(app.RPM_filtered(index,lap)));
        end
    end


    methods (Access = private)
        
        function results = GenerateDamperSpeedData(app)


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Size changed function: GPSTab
        function GPSTabSizeChanged(app, event)
            position = app.GPSTab.Position;
            
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            %Once the importData button is pressed this function starts.
            Startup(app);
        end

        % Menu selected function: SelectStartPointMenu
        function SelectStartPointMenuSelected(app, event)
            %When Data -> Startpoint is selected this function is started.
            f = figure;
            scatter(app.Lat_filtered(:,2),app.Long_filtered(:,2));
            [app.Selected_Lat,app.Selected_Long]=ginput(2);
            close(f);
            
            %FilterData(app);
            
        end

        % Value changed function: LapDropDown
        function LapDropDownValueChanged(app, event)
            value = str2num(app.LapDropDown.Value);
            scatter(app.UIAxes,nonzeros(app.Lat_filtered(:,value)),nonzeros(app.Long_filtered(:,value)));
            colorbar(app.UIAxes,'off');
            PlotTestGraphsTab(app,value);
        end

        % Button pushed function: SelectDataButton
        function SelectDataButtonPushed(app, event)
            value = str2num(app.LapDropDown.Value);
            colorbar(app.UIAxes,'off');
            f = figure;
            scatter(nonzeros(app.Lat_filtered(:,value)),nonzeros(app.Long_filtered(:,value)));
            [app.Selected_Lat,app.Selected_Long]=ginput(2);
            close(f);
            j = 1;
            for i = 1:1:length(app.Lat_filtered(:,value))
                if((app.Lat_filtered(i,value) >= app.Selected_Lat(1)) && (app.Lat_filtered(i,value) <= app.Selected_Lat(2))) && ((app.Long_filtered(i,value) >= app.Selected_Long(1)) && ((app.Long_filtered(i,value) <= app.Selected_Long(2))))
                    index(j) = i;
                    j = j + 1;
                end
            end
            scatter(app.UIAxes,nonzeros(app.Lat_filtered(index,value)),nonzeros(app.Long_filtered(index,value)));
            PlotSelectedData(app,value,index)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DataMenu = uimenu(app.UIFigure);
            app.DataMenu.Text = 'Data';
            app.SelectStartPointMenu = uimenu(app.DataMenu);
            app.SelectStartPointMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectStartPointMenuSelected, true);
            app.SelectStartPointMenu.Text = 'Select Start Point';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GPSTab = uitab(app.TabGroup);
            app.GPSTab.SizeChangedFcn = createCallbackFcn(app, @GPSTabSizeChanged, true);
            app.GPSTab.Title = 'GPS';
            app.UIAxes2 = uiaxes(app.GPSTab);
            title(app.UIAxes2, 'Track Speed')
            xlabel(app.UIAxes2, 'Point')
            ylabel(app.UIAxes2, 'Speed')
            app.UIAxes3 = uiaxes(app.GPSTab);
            title(app.UIAxes3, 'Altitude')
            xlabel(app.UIAxes3, 'Time')
            ylabel(app.UIAxes3, 'Altitude')
            app.ACCELTab = uitab(app.TabGroup);
            app.ACCELTab.Title = 'ACCEL';
            app.UIAxes6 = uiaxes(app.ACCELTab);
            title(app.UIAxes6, 'X Accel')
            xlabel(app.UIAxes6, 'Time')
            ylabel(app.UIAxes6, 'G')
            app.UIAxes7 = uiaxes(app.ACCELTab);
            title(app.UIAxes7, 'Y Accel')
            xlabel(app.UIAxes7, 'Time')
            ylabel(app.UIAxes7, 'G')
            app.UIAxes8 = uiaxes(app.ACCELTab);
            title(app.UIAxes8, 'Z Accel')
            xlabel(app.UIAxes8, 'Time')
            ylabel(app.UIAxes8, 'G')
            app.ROLLTab = uitab(app.TabGroup);
            app.ROLLTab.Title = 'ROLL';
            app.UIAxes9 = uiaxes(app.ROLLTab);
            title(app.UIAxes9, 'Roll')
            xlabel(app.UIAxes9, 'Time')
            ylabel(app.UIAxes9, '(Deg/s)')
            app.UIAxes10 = uiaxes(app.ROLLTab);
            title(app.UIAxes10, 'Yaw')
            xlabel(app.UIAxes10, 'Time')
            ylabel(app.UIAxes10, '(Deg/s)')
            app.UIAxes11 = uiaxes(app.ROLLTab);
            title(app.UIAxes11, 'Pitch')
            xlabel(app.UIAxes11, 'Time')
            ylabel(app.UIAxes11, '(Deg/s)')
            app.FrontPOTSTab = uitab(app.TabGroup);
            app.FrontPOTSTab.Title = 'Front POTS';
            app.UIAxes12 = uiaxes(app.FrontPOTSTab);
            title(app.UIAxes12, 'FL')
            xlabel(app.UIAxes12, 'Time')
            ylabel(app.UIAxes12, 'Voltage')
            app.UIAxes13 = uiaxes(app.FrontPOTSTab);
            title(app.UIAxes13, 'FR')
            xlabel(app.UIAxes13, 'Time')
            ylabel(app.UIAxes13, 'Voltage')
            app.RearPOTSTab = uitab(app.TabGroup);
            app.RearPOTSTab.Title = 'Rear POTS';
            app.UIAxes14 = uiaxes(app.RearPOTSTab);
            title(app.UIAxes14, 'RL')
            xlabel(app.UIAxes14, 'Time')
            ylabel(app.UIAxes14, 'Voltage (Degrees)')
            app.UIAxes15 = uiaxes(app.RearPOTSTab);
            title(app.UIAxes15, 'RR')
            xlabel(app.UIAxes15, 'Time')
            ylabel(app.UIAxes15, 'Voltage')
            app.BRAKESTab = uitab(app.TabGroup);
            app.BRAKESTab.Title = 'BRAKES';
            app.UIAxes4 = uiaxes(app.BRAKESTab);
            title(app.UIAxes4, 'Rear Brakes')
            xlabel(app.UIAxes4, 'Time')
            ylabel(app.UIAxes4, 'V')
            app.UIAxes5 = uiaxes(app.BRAKESTab);
            title(app.UIAxes5, 'Front Brakes')
            xlabel(app.UIAxes5, 'Time')
            ylabel(app.UIAxes5, 'V')
            app.General2 = uitab(app.TabGroup);
            app.General2.Title = 'General2';
            app.UIAxes16 = uiaxes(app.General2);
            title(app.UIAxes16, 'Steering Angle')
            xlabel(app.UIAxes16, '')
            ylabel(app.UIAxes16, 'Angle')
            app.UIAxes17 = uiaxes(app.General2);
            title(app.UIAxes17, 'RPM')
            xlabel(app.UIAxes17, 'Point')
            ylabel(app.UIAxes17, 'RPM')
            app.EngineTab = uitab(app.TabGroup);
            app.EngineTab.Title = 'Engine';
            app.RPMGaugeLabel = uilabel(app.EngineTab);
            app.RPMGaugeLabel.Text = 'RPM';
            app.RPMGauge = uigauge(app.EngineTab, 'circular');
            app.RPMGauge.Limits = [0 14];
            app.RPMGauge.ScaleDirection = 'counterclockwise';
            app.RPMGauge.Value = 1.4;
            app.FDamoerSpeedTab = uitab(app.TabGroup);
            app.FDamoerSpeedTab.Title = 'F. Damoer Speed';
            app.UIAxes18 = uiaxes(app.FDamoerSpeedTab);
            title(app.UIAxes18, 'Title')
            xlabel(app.UIAxes18, 'X')
            ylabel(app.UIAxes18, 'Y')
            app.UIAxes19 = uiaxes(app.FDamoerSpeedTab);
            title(app.UIAxes19, 'Title')
            xlabel(app.UIAxes19, 'X')
            ylabel(app.UIAxes19, 'Y')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Track')
            xlabel(app.UIAxes, 'Lat')
            ylabel(app.UIAxes, 'Long')
            app.LapDropDownLabel = uilabel(app.UIFigure);
            app.LapDropDownLabel.Text = 'Lap';
            app.LapDropDown = uidropdown(app.UIFigure);
            app.LapDropDown.Items = {'1'};
            app.LapDropDown.ItemsData = {'1'};
            app.LapDropDown.Editable = 'on';
            app.LapDropDown.ValueChangedFcn = createCallbackFcn(app, @LapDropDownValueChanged, true);
            app.LapDropDown.Value = '1';
            app.ImportDataButton = uibutton(app.UIFigure, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import Data';
            app.SelectDataButton = uibutton(app.UIFigure, 'push');
            app.SelectDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataButtonPushed, true);
            app.SelectDataButton.Text = 'Select Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrackDataApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end